classdef cloneDetection_Matlab_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CloneDetectorUIFigure         matlab.ui.Figure
        TargetmodelfolderpathLabel    matlab.ui.control.Label
        HighlightSelectedCloneButton  matlab.ui.control.Button
        Hyperlink2                    matlab.ui.control.Hyperlink
        Hyperlink                     matlab.ui.control.Hyperlink
        TargetmodelfolderpathEditFieldLabel_2  matlab.ui.control.Label
        ORLabel                       matlab.ui.control.Label
        RemoveButton                  matlab.ui.control.Button
        BrowsefoldersButton           matlab.ui.control.Button
        Tree2                         matlab.ui.container.Tree
        DetectClonesLabel             matlab.ui.control.Label
        DefineTargetScopeLabel        matlab.ui.control.Label
        GetSelectedBlocksButton       matlab.ui.control.Button
        AddSubsystemPatternBlocksEditField  matlab.ui.control.EditField
        AddSubsystemPatternBlocksEditFieldLabel  matlab.ui.control.Label
        Tree                          matlab.ui.container.Tree
        DefineSourceLabel             matlab.ui.control.Label
        BrowseModelsButton            matlab.ui.control.Button
        FindClonesButton              matlab.ui.control.Button
    end

    % Copyright 2023 The MathWorks, Inc.
    
    properties (Access = public)
        TargetInfo
        HilitedBlocks
    end

    methods (Access = private)
        function highlightCloneRegion(app, node)
            blockPath = Simulink.BlockPath({node.Text});
            try
                % Open the model, which is a grand parent of the block.
                parentRegion = node.Parent;
                modelNode = parentRegion.Parent;
                modelName = modelNode.Text;
                open_system(modelName);

                % If the blockPath is not valid, it will throw error.
                validate(blockPath);
                
                removeHighlighting(app);
                % For individual block selection
                hilite_system(node.Text);
                app.HilitedBlocks = [app.HilitedBlocks {node.Text}];
            catch % Selected a region or invalid selection for highlight
                if contains(node.Text, 'Region') % For region selection
                    modelNode = node.Parent;
                    modelName = modelNode.Text;
                    open_system(modelName);

                    removeHighlighting(app);
                    for childIndex = 1 : length(node.Children)
                        blockPath = node.Children(childIndex).Text;
                        hilite_system(blockPath);
                        app.HilitedBlocks = [app.HilitedBlocks {blockPath}];
                    end
                else
                    warndlg('Select a clone region to highlight.', 'Warning');
                end
            end
        end

        function removeHighlighting(app)
            % Remove highlighting from existing highlighted blocks.
            if ~isempty(app.HilitedBlocks)
                for i = 1 : length(app.HilitedBlocks)
                    try
                        hilite_system(app.HilitedBlocks{i}, 'none');
                    catch % When the model is closed the previously highlighted block not found
                    end
                end
                % Remove all blocks from 'Highlighted Blocks' container
                app.HilitedBlocks = [];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FindClonesButton
        function findClones(app, event)

            %% Validate inputs
            selectedBlocksList = app.AddSubsystemPatternBlocksEditField.Value;
            targetScope = app.TargetInfo;
            if isempty(selectedBlocksList) || isempty(targetScope)
                errordlg('Invalid input: Provide source and target scope to find clones.', 'Error');
                return;
            end

            split_path = strsplit(selectedBlocksList, ',');

            %% Start the loader:
            loadingPopup = waitbar(0, 'Detecting Clones...');
            waitbar(0.1, loadingPopup); % 10% completion to start
            
            %% Find clones in the target scope models:
            try
                [results, status] = findClonesOfSource(split_path, targetScope);
    
                if (status == 0) % Warnings thrown to Command line
                    warndlg(['There are some warnings detected during Clone Detection.' ...
                        ' Check the log on MATLAB command line for details.'], 'Warning');
                end

                if ishandle(loadingPopup)
                    waitbar(0.9, loadingPopup); % 90% completion after detecting results
                end
                
                delete(app.Tree2.Children);
                if ~isempty(results)
                    uitreenode(app.Tree2, 'Text', "Clones"+": "+results.Clones);
                    uitreenode(app.Tree2, 'Text', "Blocks In All Clones"+": "+results.BlocksInAllClones);
                    cloneRegionNumber = 1;

                    for index = 1 : length(results.CloneList)
                        % Add to the list
                        cloneData = results.CloneList(index);

                        cloneGroupNode = uitreenode(app.Tree2, 'Text', cloneData.ModelName);
    
                        for cloneIndex = 1 : length(cloneData.ClonePattern)
                            cloneNode = uitreenode(cloneGroupNode, 'Text', "Region" + cloneRegionNumber);
                            % Region number id will continue across clones
                            % from different models.
                            cloneRegionNumber = cloneRegionNumber + 1;
                            for blockIndex = 1 : length(cloneData.ClonePattern{cloneIndex})
                                % Add pattern block to the list.
                                uitreenode(cloneNode, 'Text', cloneData.ClonePattern{cloneIndex}{blockIndex});
                            end
                        end
                    end
                end
            catch ex % catching any exception that was thrown when 
                % calling the clone detection script.
                errordlg(ex.message, 'Error');
            end
            if ishandle(loadingPopup)
                close(loadingPopup); % Close the loading popup after displaying the results on GUI.
            end
        end

        % Button pushed function: GetSelectedBlocksButton
        function getBlocksForSource(app, event)
            if isempty(gcs)
               warndlg('Select a pattern of Simulink blocks you want to search.', 'Warning');
               return;
            end

            selectedBlocks = find_system(gcs, 'SearchDepth', 1,...
                'LookUnderMasks', 'on', 'Selected', 'on');
            if isempty(selectedBlocks)
               warndlg('Select a pattern of Simulink blocks you want to search.', 'Warning');
               return;
            end
            if ~isscalar(selectedBlocks)
                parent1 = get_param(selectedBlocks{1}, 'Parent');
                parent2 = get_param(selectedBlocks{2}, 'Parent');
                if (strcmp(parent1, parent2))
                    startIdx = 1;
                else
                    startIdx = 2;
                end
            else
                startIdx = 1;
            end
            val = selectedBlocks{startIdx};
            for i = startIdx+1 : length(selectedBlocks)
                val = [val ',' selectedBlocks{i}];
            end
            app.AddSubsystemPatternBlocksEditField.Value = val;
        end

        % Button pushed function: RemoveButton
        function RemoveTargetElement(app, event)
            if isempty(app.TargetInfo)
                errordlg('No items in the target scope to remove.', 'Error');
                return;
            end
            
            selectedNodes = app.Tree.SelectedNodes;
            if isempty(selectedNodes)
                errordlg('Select items from target scope to remove.', 'Error');
                return;
            end

            newVal = [];
            for targetScopeIndex = 1 : length(app.TargetInfo)
                found = 0;
                for selectedNodeIndexInTargetScope = 1 : length(selectedNodes)
                    if strcmp(app.TargetInfo{targetScopeIndex},...
                            selectedNodes(selectedNodeIndexInTargetScope).Text)
                        found = 1;
                        break;
                    end
                end
                if (found == 0)
                    newVal = [newVal {app.TargetInfo{targetScopeIndex}}];
                end
            end
            app.Tree.Children.delete;
            app.TargetInfo = newVal;
            for targetScopeIndex = 1 : length(app.TargetInfo)
                uitreenode(app.Tree, 'Text', app.TargetInfo{targetScopeIndex});
            end
        end

        % Button pushed function: BrowseModelsButton
        function BrowseFilesFcn(app, event)

            [file,path] = uigetfile('*.slx;*.mdl',...
                'Select files', 'MultiSelect', 'on');

            if isequal(file,0)
                warndlg('No target scope model selected', 'Warning');
                return;
            end
            if ischar(file)
                filePathToAdd = fullfile(path,file);
                if ismember(filePathToAdd, app.TargetInfo)
                    warndlg('Selected model is already present in the target scope.', 'Warning');
                    return;
                end
                if ~(Simulink.MDLInfo(filePathToAdd).IsLibrary)
                    app.TargetInfo = [app.TargetInfo {filePathToAdd}];
                    uitreenode(app.Tree, 'Text', fullfile(path, file));
                else
                    warndlg(fprintf('Library file %s is not supported to be added in the target scope.', file), 'Warning');
                end
            else
                libraryFilesPresent = false;
                for i = 1 : length(file)
                    filePathToAdd = fullfile(path,file{i});
                    if ismember(filePathToAdd, app.TargetInfo)
                        warning('Selected model %s is already present in the target scope.', filePathToAdd);
                        continue;
                    end
                    if ~(Simulink.MDLInfo(filePathToAdd).IsLibrary)
                        app.TargetInfo = [app.TargetInfo {filePathToAdd}];
                        uitreenode(app.Tree, 'Text', fullfile(path, file{i}));
                    else
                        libraryFilesPresent = true;
                        warning('Library file %s is not supported to be added in the target scope', file{i});
                    end
                end
                if libraryFilesPresent
                    warndlg(['Library files are not supported to be added in the target scope.' ...
                        ' Check the command line messages for details.'], 'Warning');
                end
            end
        end

        % Button pushed function: BrowsefoldersButton
        function BrowseFoldersFCn(app, event)
            folderPath = uigetdir(path, 'Select folders');
            if folderPath == 0
                warndlg('No target scope folder selected', 'Warning');
                return;
            end
            if ismember(folderPath, app.TargetInfo)
                warndlg('Selected folder is already present in the target scope.', 'Warning');
                return;
            end

            app.TargetInfo = [app.TargetInfo {folderPath}];
            uitreenode(app.Tree, 'Text', folderPath);
        end

        % Double-clicked callback: Tree2
        function cloneRegionSelectedCallback(app, event)
            node = event.InteractionInformation.Node;
            if isfile(node.Text)
                % If user double clicks on a file name, then don't do
                % anything. silently ignore it.
                return;
            end
            highlightCloneRegion(app, node);
        end

        % Button pushed function: HighlightSelectedCloneButton
        function highlightSelectedCloneRegion(app, event)
            if numel(app.Tree2.Children) <= 2
                warndlg('No clones to highlight.', 'Warning');
                return;
            end

            if isempty(app.Tree2.SelectedNodes)
                warndlg('Select a clone to highlight.', 'Warning');
                return;
            end

            node = app.Tree2.SelectedNodes(1); % Assuming only one node is selected.
            highlightCloneRegion(app, node);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CloneDetectorUIFigure = uifigure('Visible', 'off');
            app.CloneDetectorUIFigure.Name = 'Clone Detector';
            app.FindClonesButton = uibutton(app.CloneDetectorUIFigure, 'push');
            app.FindClonesButton.ButtonPushedFcn = createCallbackFcn(app, @findClones, true);
            app.FindClonesButton.Text = 'Find Clones';
            app.BrowseModelsButton = uibutton(app.CloneDetectorUIFigure, 'push');
            app.BrowseModelsButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFilesFcn, true);
            app.BrowseModelsButton.Text = 'Browse Models';
            app.DefineSourceLabel = uilabel(app.CloneDetectorUIFigure);
            app.DefineSourceLabel.Text = 'Define Source';
            app.Tree = uitree(app.CloneDetectorUIFigure);
            app.Tree.Multiselect = 'on';
            app.AddSubsystemPatternBlocksEditFieldLabel = uilabel(app.CloneDetectorUIFigure);
            app.AddSubsystemPatternBlocksEditFieldLabel.Text = 'Add Subsystem/Pattern Blocks';
            app.AddSubsystemPatternBlocksEditField = uieditfield(app.CloneDetectorUIFigure, 'text');
            app.GetSelectedBlocksButton = uibutton(app.CloneDetectorUIFigure, 'push');
            app.GetSelectedBlocksButton.ButtonPushedFcn = createCallbackFcn(app, @getBlocksForSource, true);
            app.GetSelectedBlocksButton.Text = 'Get Selected Blocks';
            app.DefineTargetScopeLabel = uilabel(app.CloneDetectorUIFigure);
            app.DefineTargetScopeLabel.Text = 'Define Target Scope';
            app.DetectClonesLabel = uilabel(app.CloneDetectorUIFigure);
            app.DetectClonesLabel.Text = 'Detect Clones';
            app.Tree2 = uitree(app.CloneDetectorUIFigure);
            app.Tree2.DoubleClickedFcn = createCallbackFcn(app, @cloneRegionSelectedCallback, true);
            app.BrowsefoldersButton = uibutton(app.CloneDetectorUIFigure, 'push');
            app.BrowsefoldersButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFoldersFCn, true);
            app.BrowsefoldersButton.Text = 'Browse folders';
            app.RemoveButton = uibutton(app.CloneDetectorUIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveTargetElement, true);
            app.RemoveButton.Text = 'Remove ';
            app.ORLabel = uilabel(app.CloneDetectorUIFigure);
            app.ORLabel.Text = 'OR';
            app.TargetmodelfolderpathEditFieldLabel_2 = uilabel(app.CloneDetectorUIFigure);
            app.TargetmodelfolderpathEditFieldLabel_2.Text = 'Find Source in Target Scope';
            app.Hyperlink = uihyperlink(app.CloneDetectorUIFigure);
            app.Hyperlink.URL = 'https://www.mathworks.com/help/slcheck/ug/clone-detection-programmatically.html';
            app.Hyperlink.Text = 'Clone Detection APIs';
            app.Hyperlink2 = uihyperlink(app.CloneDetectorUIFigure);
            app.Hyperlink2.URL = 'https://www.mathworks.com/help/slcheck/ug/identify-subsystem-clones-and-replace-them-with-library-blocks.html';
            app.Hyperlink2.Text = 'Clone Detector App';
            app.HighlightSelectedCloneButton = uibutton(app.CloneDetectorUIFigure, 'push');
            app.HighlightSelectedCloneButton.ButtonPushedFcn = createCallbackFcn(app, @highlightSelectedCloneRegion, true);
            app.HighlightSelectedCloneButton.Text = 'Highlight Selected Clone';
            app.TargetmodelfolderpathLabel = uilabel(app.CloneDetectorUIFigure);
            app.TargetmodelfolderpathLabel.Text = 'Target model /folder path';
            % Show the figure after all components are created
            app.CloneDetectorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cloneDetection_Matlab_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CloneDetectorUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.CloneDetectorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CloneDetectorUIFigure)
        end
    end
end